classdef AdjustRawImages < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        GridLayout             matlab.ui.container.GridLayout
        GridLayout2            matlab.ui.container.GridLayout
        DAPIPanel              matlab.ui.container.Panel
        GridLayout3_2          matlab.ui.container.GridLayout
        Ch4Max                 matlab.ui.control.Slider
        Ch4Min                 matlab.ui.control.Slider
        ChooseDirectoryButton  matlab.ui.control.Button
        SaveButton             matlab.ui.control.Button
        FarRedPanel            matlab.ui.container.Panel
        GridLayout5            matlab.ui.container.GridLayout
        Ch3Max                 matlab.ui.control.Slider
        Ch3Min                 matlab.ui.control.Slider
        RedPanel               matlab.ui.container.Panel
        GridLayout4            matlab.ui.container.GridLayout
        Ch2Max                 matlab.ui.control.Slider
        Ch2Min                 matlab.ui.control.Slider
        GreenPanel             matlab.ui.container.Panel
        GridLayout3            matlab.ui.container.GridLayout
        Ch1Max                 matlab.ui.control.Slider
        Ch1Min                 matlab.ui.control.Slider
        MaskButton             matlab.ui.control.Button
        ResetCropButton        matlab.ui.control.Button
        CropButton             matlab.ui.control.Button
        LoadButton             matlab.ui.control.Button
        FileSelect             matlab.ui.control.ListBox
        TabGroup               matlab.ui.container.TabGroup
        ChannelsTab            matlab.ui.container.Tab
        Grid                   matlab.ui.container.GridLayout
        AxCh4h                 matlab.ui.control.UIAxes
        AxCh4                  matlab.ui.control.UIAxes
        AxCh3h                 matlab.ui.control.UIAxes
        AxCh2h                 matlab.ui.control.UIAxes
        AxCh1h                 matlab.ui.control.UIAxes
        AxCh3                  matlab.ui.control.UIAxes
        AxCh2                  matlab.ui.control.UIAxes
        AxCh1                  matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Property % Description
    end
    
    properties (Access = public)
        file;   % File
        ch1;    % Channel 1 original
        ch2;
        ch3;   
        ch4;
        ch1m;   % Channel 1 masked
        ch2m;
        ch3m
        ch4m;
        ch1e;   % Channel 1 editted
        ch2e;
        ch3e;
        ch4e;
        msk;    % Mask in case of multiple spheroids
        crop;   % Axis limits from crop
        ch1r;
        ch2r;
        ch3r;
        ch4r;
        meta;
        directory;
    end
    
    methods (Access = private)
        
        function UpdateFileSelect(app)
            
            % Get files in TIF directory
            files = dir(app.directory);
            files = {files.name};
            idx = false(length(files));
            for i = 1:length(files)
                idx(i) = length(files{i}) > 3 && strcmp(files{i}(end-2:end),"tif");
            end
            files = {files{idx}};

            % No files left?
            if isempty(files)
                delete(app);
            end
            
            % Update file selector
            app.FileSelect.Items = files;
            
        end
        
        function res = readlines2(~,fname)
    
            fid  = fopen(fname,'r');
            line = fgetl(fid);
            res  = [string(line)];
            while ischar(line)
                line = fgetl(fid);
                res(end+1) = string(line);
            end
            res = res';
            fclose(fid);
        
        end
        
        function RefreshChannels(app)
            imshow(app.ch1e,'Parent',app.AxCh1);
            imshow(app.ch2e,'Parent',app.AxCh2);
            imshow(app.ch3e,'Parent',app.AxCh3);
            imshow(app.ch4e,'Parent',app.AxCh4);
            xlim(app.AxCh1,app.crop(1:2));
            ylim(app.AxCh1,app.crop(3:4));
            xlim(app.AxCh2,app.crop(1:2));
            ylim(app.AxCh2,app.crop(3:4));
            xlim(app.AxCh3,app.crop(1:2));
            ylim(app.AxCh3,app.crop(3:4));
            xlim(app.AxCh4,app.crop(1:2));
            ylim(app.AxCh4,app.crop(3:4));
            
            histogram(app.AxCh1h,app.ch1e(:),0.0001:0.1:1.0,'EdgeColor','none','FaceColor','g');
            histogram(app.AxCh2h,app.ch2e(:),0.0001:0.1:1.0,'EdgeColor','none','FaceColor','r');
            histogram(app.AxCh3h,app.ch3e(:),0.0001:0.1:1.0,'EdgeColor','none','FaceColor','m');
            histogram(app.AxCh4h,app.ch4e(:),0.0001:0.1:1.0,'EdgeColor','none','FaceColor','b');
           
        end
        
        function RefreshRange(app,ch)
            
            switch ch
                
                case 1
                    app.ch1e = imadjust(app.ch1m,sort(app.ch1r));
                    
                case 2
                    app.ch2e = imadjust(app.ch2m,sort(app.ch2r));
                    
                case 3
                    app.ch3e = imadjust(app.ch3m,sort(app.ch3r));
                
                case 4
                    app.ch4e = imadjust(app.ch4m,sort(app.ch4r));
                    
            end
            
            RefreshChannels(app);
            
        end
        
        function LoadImage(app)
            
            app.file = join([app.directory,"/",app.FileSelect.Value],"");
            [raw,app.meta] = raw_tif_read(app,app.file);
           
            app.ch1  = raw(:,:,2); % green
            app.ch2  = raw(:,:,3); % red
            app.ch3  = raw(:,:,4); % mag
            app.ch4  = raw(:,:,1); % dapi
            
            app.ch1m = app.ch1;
            app.ch2m = app.ch2;
            app.ch3m = app.ch3;
            app.ch4m = app.ch4;
            
            app.ch1e = app.ch1m;
            app.ch2e = app.ch2m;
            app.ch3e = app.ch3m;
            app.ch4e = app.ch4m;
            
            [m,n] = size(app.ch1);
            app.crop = [0,m,0,n] + 0.5;
            
            % Reset ranges
            app.Ch1Min.Value = 0.0;
            app.Ch2Min.Value = 0.0;
            app.Ch3Min.Value = 0.0;
            app.Ch4Min.Value = 0.0;
            
            app.Ch1Max.Value = 1.0;
            app.Ch2Max.Value = 1.0;
            app.Ch3Max.Value = 1.0;
            app.Ch4Max.Value = 1.0;
            
            RefreshChannels(app);
            
            
        end
        
        function [raw,meta] = raw_tif_read(app,fname)

    if strcmp(extractBetween(fname,strlength(fname)-2,strlength(fname)),"tif")
        fname = extractBetween(fname,1,strlength(fname)-4);
    end

    fmeta = imfinfo(sprintf("%s.tif",fname));
    nch  = length(fmeta);

    % USE INFO TXT FILE TO GET EXCITATION WAVELENGTH OF EACH CHANNEL
    if 0 == exist(sprintf("%s.txt",fname),'file')
        error("No text file for this image.");
    end
    info = readlines2(app,sprintf("%s.txt",fname));
    names  = [];
    values = [];
    for i = 1:length(info)
        row = split(info(i),"=");
        if length(row) ~= 2
            continue;
        end
        names  = [names; strtrim(row(1))];
        values = [values; strtrim(row(2))];
    end

    % FIND LASERS IN INFO (looking for "- Laser LD405")
    lasers = [];
    for i = 1:length(names)
        name = names(i);
        if strlength(names(i)) > 13 && strcmp(extractBetween(name,1,7),"- Laser")
            lasers = [lasers; extractBetween(name,11,13)];
        end
    end
    lasers = str2double(unique(lasers));

    ch_wavelength = zeros(nch,1);

    for ch = 1:nch
        laser_idx = str2double(values(find(strcmp(names,sprintf("- Channel CH%d linked laser index #1",ch)),1,'first')));
        ch_wavelength(ch) = lasers(laser_idx + 1);
    end
    
    % GET CREATION DATE/TIME
    creation = values(find(strcmp(names,"general creationDateTime #1"),1,'first'));
    creation = split(creation,"T");
    creationDate = datetime(creation(1));
    creationTime = creation(2);
    
    % Extract image for each channel (or zeros otherwise)
    %   1 - 405 - DAPI
    %   2 - 488 - Green
    %   3 - 561 - Red
    %   4 - 640 - DRAQ7
    ChWv = [405,488,561,640];
    raw  = zeros(fmeta(1).Width,fmeta(1).Width,4);
    for i = 1:4
        if any(ChWv(i) == ch_wavelength)
            tif_idx = find(ChWv(i) == ch_wavelength,1,'first');
            raw(:,:,i) = double(imread(sprintf("%s.tif",fname),tif_idx));
            raw(:,:,i) = raw(:,:,i) / max(raw(:,:,i),[],'all');
        end
    end
    
    meta.Resolution    = fmeta(1).XResolution;
    meta.CreationDate  = creationDate;
    meta.CreationTime  = creationTime;
    
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            app.directory = uigetdir;
            UpdateFileSelect(app);
            LoadImage(app);
            
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            
            LoadImage(app);
            
        end

        % Button pushed function: CropButton
        function CropButtonPushed(app, event)
            
            roi = drawrectangle(app.AxCh1);
            pos = roi.Position;
            app.crop = [pos(1),pos(1) + max(10,pos(3)),pos(2),pos(2) + max(10,pos(4))];
            RefreshChannels(app);
            
        end

        % Button pushed function: MaskButton
        function MaskButtonPushed(app, event)
            
            roi = drawpolygon(app.AxCh1);
            pos = roi.Position;
            [m,n] = size(app.ch1);
            app.msk = poly2mask(pos(:,1),pos(:,2),m,n);
            app.ch1m = app.msk .* app.ch1;
            app.ch1e = app.msk .* app.ch1e;
            app.ch2m = app.msk .* app.ch2;
            app.ch2e = app.msk .* app.ch2e;
            app.ch3m = app.msk .* app.ch3;
            app.ch3e = app.msk .* app.ch3e;
            app.ch4m = app.msk .* app.ch4;
            app.ch4e = app.msk .* app.ch4e;
            RefreshChannels(app);
            
        end

        % Button pushed function: ResetCropButton
        function ResetCropButtonPushed(app, event)
            [m,n] = size(app.ch1);
            app.crop = [0,m,0,n] + 0.5;
            RefreshChannels(app);
        end

        % Value changed function: Ch1Min
        function Ch1MinValueChanged(app, event)
            
            app.ch1r = [app.Ch1Min.Value, app.Ch1Max.Value];
            RefreshRange(app,1);
            
        end

        % Value changed function: Ch1Max
        function Ch1MaxValueChanged(app, event)
            
            app.ch1r = [app.Ch1Min.Value, app.Ch1Max.Value];
            RefreshRange(app,1);
            
        end

        % Value changed function: Ch2Min
        function Ch2MinValueChanged(app, event)
            
            app.ch2r = [app.Ch2Min.Value, app.Ch2Max.Value];
            RefreshRange(app,2);
            
        end

        % Value changed function: Ch2Max
        function Ch2MaxValueChanged(app, event)
            
            app.ch2r = [app.Ch2Min.Value, app.Ch2Max.Value];
            RefreshRange(app,2);
            
        end

        % Value changed function: Ch3Min
        function Ch3MinValueChanged(app, event)
            
            app.ch3r = [app.Ch3Min.Value, app.Ch3Max.Value];
            RefreshRange(app,3);
            
        end

        % Value changed function: Ch3Max
        function Ch3MaxValueChanged(app, event)
            
            app.ch3r = [app.Ch3Min.Value, app.Ch3Max.Value];
            RefreshRange(app,3);
            
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            
            % Create directory if needed
            [~,~,~] = mkdir(app.directory,"Original");
            [~,~,~] = mkdir(app.directory,"Adjusted");
            
            % Move old image to "Original" folder
            fold = join([app.directory,"/",app.FileSelect.Value],"");
            fnew = join([app.directory,"/Original/",app.FileSelect.Value],"");
            movefile(fold,fnew);
            
            % Also move meta text file
            rawfname = extractBetween(app.FileSelect.Value,1,length(app.FileSelect.Value) - 4);
            told = join([app.directory,"/",rawfname,".txt"],"");
            tnew = join([app.directory,"/Original/",rawfname,".txt"],"");
            movefile(told,tnew)
            
            % Create new image in "Adjusted"
            fname = char(join([app.directory,"/Adjusted/",app.FileSelect.Value],""));
            imwrite(app.ch4e,fname,"Resolution",[app.meta.Resolution,app.meta.Resolution],"Description",string(app.meta.CreationDate));
            imwrite(app.ch1e,fname,"Resolution",[app.meta.Resolution,app.meta.Resolution],"Description",string(app.meta.CreationDate),"writemode","append");
            imwrite(app.ch2e,fname,"Resolution",[app.meta.Resolution,app.meta.Resolution],"Description",string(app.meta.CreationDate),"writemode","append");
            imwrite(app.ch3e,fname,"Resolution",[app.meta.Resolution,app.meta.Resolution],"Description",string(app.meta.CreationDate),"writemode","append");
            
            % Save composite png image (without DAPI)
            blank   = 0*app.ch2e;
            red     = cat(3,app.ch2e,blank,blank);
            grn     = cat(3,blank,app.ch1e,blank);
            mag     = cat(3,app.ch3e,blank,app.ch3e);
            img     = grn + red + mag;
            imwrite(img,join([fname(1:end-4),".png"],""));
            
            % Update and load
            UpdateFileSelect(app);
            LoadImage(app);
            
        end

        % Button pushed function: ChooseDirectoryButton
        function ChooseDirectoryButtonPushed(app, event)
            app.directory = uigetdir;
        end

        % Value changed function: Ch4Min
        function Ch4MinValueChanged(app, event)
            
            app.ch4r = [app.Ch4Min.Value, app.Ch4Max.Value];
            RefreshRange(app,4);
            
        end

        % Value changed function: Ch4Max
        function Ch4MaxValueChanged(app, event)
            
            app.ch4r = [app.Ch4Min.Value, app.Ch4Max.Value];
            RefreshRange(app,4);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.TabGroup = uitabgroup(app.GridLayout);
            app.ChannelsTab = uitab(app.TabGroup);
            app.ChannelsTab.Title = 'Channels';
            app.Grid = uigridlayout(app.ChannelsTab);
            app.AxCh1 = uiaxes(app.Grid);
            title(app.AxCh1, 'Green')
            zlabel(app.AxCh1, 'Z')
            app.AxCh1.XTick = [];
            app.AxCh1.YTick = [];
            app.AxCh2 = uiaxes(app.Grid);
            title(app.AxCh2, 'Red')
            zlabel(app.AxCh2, 'Z')
            app.AxCh2.XTick = [];
            app.AxCh2.YTick = [];
            app.AxCh3 = uiaxes(app.Grid);
            title(app.AxCh3, 'Far Red')
            zlabel(app.AxCh3, 'Z')
            app.AxCh3.XTick = [];
            app.AxCh3.YTick = [];
            app.AxCh1h = uiaxes(app.Grid);
            xlabel(app.AxCh1h, 'Value')
            zlabel(app.AxCh1h, 'Z')
            app.AxCh1h.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.AxCh1h.YTick = [];
            app.AxCh2h = uiaxes(app.Grid);
            xlabel(app.AxCh2h, 'Value')
            zlabel(app.AxCh2h, 'Z')
            app.AxCh2h.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.AxCh2h.YTick = [];
            app.AxCh3h = uiaxes(app.Grid);
            xlabel(app.AxCh3h, 'Value')
            zlabel(app.AxCh3h, 'Z')
            app.AxCh3h.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.AxCh3h.YTick = [];
            app.AxCh4 = uiaxes(app.Grid);
            title(app.AxCh4, 'DAPI')
            zlabel(app.AxCh4, 'Z')
            app.AxCh4.XTick = [];
            app.AxCh4.YTick = [];
            app.AxCh4h = uiaxes(app.Grid);
            xlabel(app.AxCh4h, 'Value')
            zlabel(app.AxCh4h, 'Z')
            app.AxCh4h.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.AxCh4h.YTick = [];
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.FileSelect = uilistbox(app.GridLayout2);
            app.LoadButton = uibutton(app.GridLayout2, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.CropButton = uibutton(app.GridLayout2, 'push');
            app.CropButton.ButtonPushedFcn = createCallbackFcn(app, @CropButtonPushed, true);
            app.CropButton.Text = 'Crop';
            app.ResetCropButton = uibutton(app.GridLayout2, 'push');
            app.ResetCropButton.ButtonPushedFcn = createCallbackFcn(app, @ResetCropButtonPushed, true);
            app.ResetCropButton.Text = 'Reset Crop';
            app.MaskButton = uibutton(app.GridLayout2, 'push');
            app.MaskButton.ButtonPushedFcn = createCallbackFcn(app, @MaskButtonPushed, true);
            app.MaskButton.Text = 'Mask';
            app.GreenPanel = uipanel(app.GridLayout2);
            app.GreenPanel.Title = 'Green';
            app.GridLayout3 = uigridlayout(app.GreenPanel);
            app.Ch1Min = uislider(app.GridLayout3);
            app.Ch1Min.Limits = [0 1];
            app.Ch1Min.MajorTickLabels = {''};
            app.Ch1Min.ValueChangedFcn = createCallbackFcn(app, @Ch1MinValueChanged, true);
            app.Ch1Max = uislider(app.GridLayout3);
            app.Ch1Max.Limits = [0 1];
            app.Ch1Max.MajorTickLabels = {''};
            app.Ch1Max.ValueChangedFcn = createCallbackFcn(app, @Ch1MaxValueChanged, true);
            app.Ch1Max.Value = 1;
            app.RedPanel = uipanel(app.GridLayout2);
            app.RedPanel.Title = 'Red';
            app.GridLayout4 = uigridlayout(app.RedPanel);
            app.Ch2Min = uislider(app.GridLayout4);
            app.Ch2Min.Limits = [0 1];
            app.Ch2Min.MajorTickLabels = {''};
            app.Ch2Min.ValueChangedFcn = createCallbackFcn(app, @Ch2MinValueChanged, true);
            app.Ch2Max = uislider(app.GridLayout4);
            app.Ch2Max.Limits = [0 1];
            app.Ch2Max.MajorTickLabels = {''};
            app.Ch2Max.ValueChangedFcn = createCallbackFcn(app, @Ch2MaxValueChanged, true);
            app.Ch2Max.Value = 1;
            app.FarRedPanel = uipanel(app.GridLayout2);
            app.FarRedPanel.Title = 'Far Red';
            app.GridLayout5 = uigridlayout(app.FarRedPanel);
            app.Ch3Min = uislider(app.GridLayout5);
            app.Ch3Min.Limits = [0 1];
            app.Ch3Min.MajorTickLabels = {''};
            app.Ch3Min.ValueChangedFcn = createCallbackFcn(app, @Ch3MinValueChanged, true);
            app.Ch3Max = uislider(app.GridLayout5);
            app.Ch3Max.Limits = [0 1];
            app.Ch3Max.MajorTickLabels = {''};
            app.Ch3Max.ValueChangedFcn = createCallbackFcn(app, @Ch3MaxValueChanged, true);
            app.Ch3Max.Value = 1;
            app.SaveButton = uibutton(app.GridLayout2, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save & Next';
            app.ChooseDirectoryButton = uibutton(app.GridLayout2, 'push');
            app.ChooseDirectoryButton.ButtonPushedFcn = createCallbackFcn(app, @ChooseDirectoryButtonPushed, true);
            app.ChooseDirectoryButton.Text = 'Choose Directory';
            app.DAPIPanel = uipanel(app.GridLayout2);
            app.DAPIPanel.Title = 'DAPI';
            app.GridLayout3_2 = uigridlayout(app.DAPIPanel);
            app.Ch4Min = uislider(app.GridLayout3_2);
            app.Ch4Min.Limits = [0 1];
            app.Ch4Min.MajorTickLabels = {''};
            app.Ch4Min.ValueChangedFcn = createCallbackFcn(app, @Ch4MinValueChanged, true);
            app.Ch4Max = uislider(app.GridLayout3_2);
            app.Ch4Max.Limits = [0 1];
            app.Ch4Max.MajorTickLabels = {''};
            app.Ch4Max.ValueChangedFcn = createCallbackFcn(app, @Ch4MaxValueChanged, true);
            app.Ch4Max.Value = 1;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AdjustRawImages

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end